classdef Segmenter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ComparedwithMIASdatasetLabel   matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        Panel                          matlab.ui.container.Panel
        SaveLabelsButton               matlab.ui.control.Button
        LabelsComparisonButton         matlab.ui.control.Button
        LabelingButton                 matlab.ui.control.Button
        Label_7                        matlab.ui.control.Label
        DICELabel                      matlab.ui.control.Label
        DICEIndex                      matlab.ui.control.Label
        SSIMLabel                      matlab.ui.control.Label
        SSIMIndex                      matlab.ui.control.Label
        bfscoreLabel                   matlab.ui.control.Label
        JaccardLabel                   matlab.ui.control.Label
        bfscoreindex                   matlab.ui.control.Label
        JaccardIndex                   matlab.ui.control.Label
        ByMCFaustoCortesLabel          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ROISemiAutomaticSegmentationandLabeling  matlab.ui.control.Label
        ProcessingDisplayPanel         matlab.ui.container.Panel
        StructuralComparisonLabel      matlab.ui.control.Label
        SegmentationAreaLabel          matlab.ui.control.Label
        OriginalImageLabel             matlab.ui.control.Label
        Stru_Comp                      matlab.ui.control.UIAxes
        SegmentedROI                   matlab.ui.control.UIAxes
        OriginalImage                  matlab.ui.control.UIAxes
        ControlsPanel                  matlab.ui.container.Panel
        CLAHEControlsPanel             matlab.ui.container.Panel
        CLAHERefreshButton             matlab.ui.control.Button
        clipi_histo                    matlab.ui.control.Label
        reg_cont                       matlab.ui.control.Label
        yreg                           matlab.ui.control.EditField
        cliping                        matlab.ui.control.EditField
        xreg                           matlab.ui.control.EditField
        ROIValidationSelectorButtonGroup  matlab.ui.container.ButtonGroup
        BreastButton_Validation        matlab.ui.control.ToggleButton
        MamaButton_Validation          matlab.ui.control.ToggleButton
        PectoralButton_Validation      matlab.ui.control.ToggleButton
        ROIOverlaySelectorButtonGroup  matlab.ui.container.ButtonGroup
        BreastButton_Laboevr           matlab.ui.control.ToggleButton
        MamaButton_Laboevr             matlab.ui.control.ToggleButton
        PectoralButton_Laboevr         matlab.ui.control.ToggleButton
        ROIOverlayedButton             matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        DefaultdistancePanel           matlab.ui.container.Panel
        DefaultdistanceforPM160DefaultdistanceforBM35Label_2  matlab.ui.control.Label
        DefaultdistanceforPM160DefaultdistanceforBM35Label  matlab.ui.control.Label
        SettingDistanceEditField       matlab.ui.control.EditField
        SettingDistanceEditFieldLabel  matlab.ui.control.Label
        BinarizedROILabel              matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        PectoralBW                     matlab.ui.control.Button
        BreastBW                       matlab.ui.control.Button
        RegiontoSegmentLabel           matlab.ui.control.Label
        ValidationLabel                matlab.ui.control.Label
        ReferencecomparisonButton      matlab.ui.control.Button
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        PectoralSegment                matlab.ui.control.Button
        BreastSegment                  matlab.ui.control.Button
        ImageenhancementDropDown       matlab.ui.control.DropDown
        ImageenhancementLabel          matlab.ui.control.Label
        LoadImageButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CLAHEControlsPanel.Enable = "off";
            clear all
            clear var
            
            
        end

        % Callback function
        function LoadImageButtonPushed(app, event)
        
        end

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Callback function
        function ImageNumValueChanged(app, event)


        end

        % Callback function: LoadImageButton, OriginalImage
        function LoadButtonPushed(app, event)
        global I pathname filename Imask labeled_folder Aqui_coloca_tu_raiz; %set global variables
        Aqui_coloca_tu_raiz = 'C:\Users\soloe\Desktop\Proyecto FInal\MIAS';
        [filename,pathname] = uigetfile({'*.png'},...
               'Select a file',(strcat(Aqui_coloca_tu_raiz,'\Images'))); %set input file type
        I = imread(strcat(pathname,filename)); %read the file.
        axes(app.OriginalImage); %choose Iori as the display region.
        imshow(I,'Parent',app.OriginalImage)
        Imask = imread( strcat ( strcat (Aqui_coloca_tu_raiz,'\Masks\'),filename ));
        labeled_folder = (strcat(Aqui_coloca_tu_raiz,'\Labeled'));
        end

        % Value changed function: ImageenhancementDropDown
        function ImageenhancementDropDownValueChanged(app, event)
            value = app.ImageenhancementDropDown.Value;

            global I pathname filename bin lab over refer nulab xreg yreg clip; %set global variables
            lab = 0;
            bin = 0;
            over = 0;
            refer = 0;
            nulab = 0;
            xreg = 0;
            yreg = 0;
            clip = 0;
            I = imread(strcat(pathname,filename)); %read the file.
            
            if value == "None"
                axes(app.OriginalImage); %choose Iori as the display region.
                imshow((uint8(I)),[],'Parent',app.OriginalImage)
                app.CLAHEControlsPanel.Enable = "off";

            elseif value == "Gauss HPF"
                I = Ghpf((double(I)),0.759,5);                 % Imagen filtro suavizante
                axes(app.OriginalImage); %choose Iori as the display region.
                imshow((uint8(I)),[],'Parent',app.OriginalImage)
                app.CLAHEControlsPanel.Enable = "off";

            elseif value == "Gauss HPF Aperture"
                se1 = strel('square',35);           % Elemento estructurante cuadrado
                Ihpf = Ghpf((double(I)),0.9,5);               % Imagen filtro suavizante
                I = imopen((double(Ihpf)),se1);                  % Imagen filtro bordes-apertura
                axes(app.OriginalImage); %choose Iori as the display region.
                imshow((uint8(I)),[],'Parent',app.OriginalImage)
                app.CLAHEControlsPanel.Enable = "off";

            elseif value == "CLAHE"
                clip = get(app.cliping);
                clip = clip.Value;
                clip = str2num(clip);
                xreg = get(app.xreg);
                xreg = xreg.Value;
                xreg = str2num(xreg);
                yreg = get(app.yreg);
                yreg = yreg.Value;
                yreg = str2num(yreg);
                if xreg == 0
                    xreg = 1;
                else
                end
                if yreg == 0
                    yreg = 2;
                else
                end
                if clip == 0
                    clip = 0.3;
                else
                end
                I = clahe((double(I)),[xreg yreg],clip);          % 8 Imagen con CLAHE
                axes(app.OriginalImage);            %choose Iori as the display region.
                imshow((uint8(I)),[],'Parent',app.OriginalImage)
                app.CLAHEControlsPanel.Enable = "on";

            elseif value == "Complement"
                I = imcomplement(I);          % 8 Imagen en negativo
                axes(app.OriginalImage);            %choose Iori as the display region.
                imshow((uint8(I)),[],'Parent',app.OriginalImage)
                app.CLAHEControlsPanel.Enable = "off";

            elseif value == "Gauss LPF"
                I = Glpf((double(I)),12,5);                % 3 Imagen filtro bordes
                axes(app.OriginalImage);            %choose Iori as the display region.
                imshow((uint8(I)),[],'Parent',app.OriginalImage)
                app.CLAHEControlsPanel.Enable = "off";
            elseif value == "Fuzzy"
                I = fuzzyenh((double(I)));                % 3 Imagen filtro bordes
                axes(app.OriginalImage);            %choose Iori as the display region.
                imshow((uint8(I)),[],'Parent',app.OriginalImage)
                app.CLAHEControlsPanel.Enable = "off";
            else
            end
        end

        % Callback function
        function ResetButtonPushed(app, event)

        end

        % Button pushed function: BreastSegment
        function BreastSegmentPushed(app, event)
            global I J1ma R1ma G1ma ses M N th lab bin ori; %set global variables
            
            set(app.JaccardIndex,'Text',(''));
            set(app.DICEIndex,'Text',(''));
            set(app.bfscoreindex,'Text',(''));
            set(app.SSIMIndex,'Text',(''));   
            lab =1;
            bin =1;
            ses = strel('disk',10);
            th = get(app.SettingDistanceEditField);
            th = th.Value;
            th = str2num(th);
            if th == 0
                th = 160;
            end
            I = double(getimage((app.OriginalImage)));
            [M,N] = size(I);
            axes(app.OriginalImage); %choose Iori as the display region.
            imshow(((I)),[],'Parent',app.OriginalImage)
            hold (app.OriginalImage,"on")
            ori = orientation(I,100);
            if count(ori,'Right') == 1
                i0 =  fix(M/25); j0 = fix(N/25); the = th;
            else
                i0 = fix(M/25); j0 = fix(N-(N/25)); the = th;
            end
            plot(app.OriginalImage,j0,i0,'b*','LineWidth',2)
            plot(app.OriginalImage,j0,i0,'ro','LineWidth',2)
            hold (app.OriginalImage,"off")
            %%
            [J1ma,R1ma,G1ma] = segregrowIn(((I)),ses,th);
            axes(app.SegmentedROI); %choose Iori as the display region.
            imshow((uint8(J1ma)),'Parent',app.SegmentedROI)
            hold (app.SegmentedROI,"on")
            contour = labeloverlay((uint8(J1ma)),G1ma);
            imshow(contour,'Parent',app.SegmentedROI)
            app.SettingDistanceEditField.Value = '0';

%%-----------------Función de Segmentación por región----------------------
            function [J,R,G] = segregrowIn(Im,se,th)
                [M,N] = size(Im);
                R = zeros(size(Im));
                ori = orientation(Im,100);
                if count(ori,'Right') == 1
                    i0 =  fix(M/25); j0 = fix(N/25); the = th;
                else
                    i0 = fix(M/25); j0 = fix(N-(N/25)); the = th;
                end
                Im = medfilt2(Im,[7 7]);
                g = Im(i0,j0);
                R(i0,j0) = 1;
                n = 1;
                axes(app.SegmentedROI); %choose Mamaseg as the display region.
                while (n>0)
                    T = imdilate(R,se);
                    G = and(T,not(R));
                    ii = find(G==1);
                    n = length(ii);
                    m = 0;
                    if (n>0)
                        for i=1:n
                            if abs(Im(ii(i))-g)<the
                                R(ii(i))=1;
                                m = m+1;
                            end
                        end
                    end
                    n = m;
                    J = Im;
                    jj = find(R==0);
                    J(jj)=255;
                    J = uint8(J);
                    imshow(J,'Parent',app.SegmentedROI)
                    drawnow
                    pause(-2.5)
                end
            end

        end

        % Button pushed function: PectoralSegment
        function PectoralSegmentPushed(app, event)
            global I J1pe R1pe G1pe  ses M N th lab bin ori; %set global variables
            
            set(app.JaccardIndex,'Text',(''));
            set(app.DICEIndex,'Text',(''));
            set(app.bfscoreindex,'Text',(''));
            set(app.SSIMIndex,'Text',(''));
            lab =2;
            bin = 2;
            ses = strel('disk',10);
            th = get(app.SettingDistanceEditField);
            th = th.Value;
            th = str2num(th);
            if th == 0
                th = 55;
            end
            I = double(getimage((app.OriginalImage)));
            [M,N] = size(I);
            axes(app.OriginalImage); %choose Iori as the display region.
            imshow(((I)),[],'Parent',app.OriginalImage)
            hold (app.OriginalImage,"on")
            ori = orientation(I,100);
            if count(ori,'Right') == 1
                i0 =  fix(M/25); j0 = fix(N/25); the = th;
            else
                i0 = fix(M/25); j0 = fix(N-(N/25)); the = th;
            end
            plot(app.OriginalImage,j0,i0,'b*','LineWidth',2)
            plot(app.OriginalImage,j0,i0,'ro','LineWidth',2)
            hold (app.OriginalImage,"off")
            %%
            [J1pe,R1pe,G1pe] = segregrowIn(((I)),ses,th);
            axes(app.SegmentedROI); %choose Iori as the display region.
            imshow((uint8(J1pe)),'Parent',app.SegmentedROI)
            hold (app.SegmentedROI,"on")
            contour = labeloverlay((uint8(J1pe)),G1pe);
            imshow(contour,'Parent',app.SegmentedROI)
            app.SettingDistanceEditField.Value = '0';
%%-----------------Función de Segmentación por región----------------------
            function [J,R,G] = segregrowIn(Im,se,th)
                [M,N] = size(Im);
                R = zeros(size(Im));
                ori = orientation(Im,100);
                if count(ori,'Right') == 1
                    i0 =  fix(M/25); j0 = fix(N/25); the = th;
                else
                    i0 = fix(M/25); j0 = fix(N-(N/25)); the = th;
                end
                Im = medfilt2(Im,[7 7]);
                g = Im(i0,j0);
                R(i0,j0) = 1;
                n = 1;
                axes(app.SegmentedROI); %choose Mamaseg as the display region.
                while (n>0)
                    T = imdilate(R,se);
                    G = and(T,not(R));
                    ii = find(G==1);
                    n = length(ii);
                    m = 0;
                    if (n>0)
                        for i=1:n
                            if abs(Im(ii(i))-g)<the
                                R(ii(i))=1;
                                m = m+1;
                            end
                        end
                    end
                    n = m;
                    J = Im;
                    jj = find(R==0);
                    J(jj)=255;
                    J = uint8(J);
                    imshow(J,'Parent',app.SegmentedROI)
                    drawnow
                    pause(-2.5)
                end
            end

        end

        % Button pushed function: ROIOverlayedButton
        function ROIOverlayedButtonPushed(app, event)
        global Rnp I R1pe R1ma al ms jacc bf colmap over sstru dce Imask...
            maROI I2 nulab; %set global variable
        colmap = 'cool';   
        al = 0.8;
        if over == 1
            Rnp = R1ma;
            nulab = 2;
            maROI = imsubtract((double(R1ma)),(double(R1pe)));
            I2 = labeloverlay((abs(uint8(I))),(abs(maROI)),"Colormap",'autumn','Transparency',al);
            imshow(I2,'Parent',app.Stru_Comp);
            ms = Lab_section(I,Imask,nulab);
            Rnp = imbinarize(Rnp);
            jacc = num2str(jaccard(ms,Rnp));
            dce = num2str(dice(ms,Rnp));
            bf = num2str(bfscore(ms,Rnp));
            sstru = num2str(ssim((double(ms)),(double(Rnp))));
            set(app.JaccardIndex,'Text',(jacc));
            set(app.DICEIndex,'Text',(dce));
            set(app.bfscoreindex,'Text',(bf));
            set(app.SSIMIndex,'Text',(sstru));
        elseif over == 2
               [M,N] = size(I);
               contador = 0;
               blab = ones(size(I));
               for i = 1:M
                   for j = 1:N
                       if Imask(i,j) == 2 || Imask(i,j) == 3
                           blab(i,j) = Imask(i,j);
                       else
                           blab(i,j)= 1;
                           contador = contador+1;
                       end
                   end
                   blab = uint8(blab);
               end
            ms = imbinarize(blab);
            I2 = labeloverlay((uint8(I)),(abs(R1ma)),"Colormap",'autumn','Transparency',al);
            Rnp = R1ma;
            imshow(I2,'Parent',app.Stru_Comp);
            %ms  = Lab_section(I,Imask,nulab);
            Rnp = imbinarize((double(Rnp)));
            jacc = num2str(jaccard(ms,Rnp));
            dce = num2str(dice(ms,Rnp));
            bf = num2str(bfscore(ms,Rnp));
            sstru = num2str(ssim((double(ms)),(double(Rnp))));
            set(app.JaccardIndex,'Text',(jacc));
            set(app.DICEIndex,'Text',(dce));
            set(app.bfscoreindex,'Text',(bf));
            set(app.SSIMIndex,'Text',(sstru));
        else
            Rnp = R1pe;
            nulab = 3;
            I2 = labeloverlay((uint8(I)),(abs(Rnp)),"Colormap",'autumn','Transparency',al);
            imshow(I2,'Parent',app.Stru_Comp);
            ms = Lab_section(I,Imask,nulab);
            Rnp = imbinarize(Rnp);
            jacc = num2str(jaccard(ms,Rnp));
            dce = num2str(dice(ms,Rnp));
            bf = num2str(bfscore(ms,Rnp));
            sstru = num2str(ssim((double(ms)),(double(Rnp))));
            set(app.JaccardIndex,'Text',(jacc));
            set(app.DICEIndex,'Text',(dce));
            set(app.bfscoreindex,'Text',(bf));
            set(app.SSIMIndex,'Text',(sstru));
        end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed2(app, event)
            axes(app.OriginalImage);
            cla (app.OriginalImage,"reset");
            app.OriginalImage.XTick = [];
            app.OriginalImage.YTick = [];
            axes(app.SegmentedROI);
            cla (app.SegmentedROI,"reset");
            app.SegmentedROI.XTick = [];
            app.SegmentedROI.YTick = [];
            axes(app.Stru_Comp);
            cla (app.Stru_Comp,"reset");
            app.Stru_Comp.XTick = [];
            app.Stru_Comp.YTick = [];
            clear var
            clear all
            close all
            clc
        end

        % Button pushed function: ReferencecomparisonButton
        function ReferencecomparisonButtonPushed(app, event)
            global Jnp J1ma J1pe B R1pe Imask jacc dce bf maROI sstru Rnp refer I nulab ms colmap al;
            if refer == 1
                nulab = 2;
                maROI = imsubtract((double(J1ma)),(double(J1pe)));
                maROI = imcomplement (maROI);
                maROI = im2gray(maROI);
                B = labeloverlay((imcomplement(uint8(maROI))),Imask,'Transparency',al,'Colormap',colmap);
                axes(app.Stru_Comp);
                imshow(((B)),[],'Parent',app.Stru_Comp)
                ms = Lab_section(I,Imask,nulab);
                Rnp = imbinarize(double(maROI));
                jacc = num2str(jaccard(ms,Rnp));
                dce = num2str(dice(ms,Rnp));
                bf = num2str(bfscore(ms,Rnp));
                set(app.JaccardIndex,'Text',(jacc));
                set(app.DICEIndex,'Text',(dce));
                set(app.bfscoreindex,'Text',(bf));

            elseif refer == 2
               [M,N] = size(I);
               contador = 0;
               blab = ones(size(I));
               for i = 1:M
                   for j = 1:N
                       if Imask(i,j) == 2 || Imask(i,j) == 3
                           blab(i,j) = Imask(i,j);
                       else
                           blab(i,j)= 1;
                           contador = contador+1;
                       end
                   end
                   blab = uint8(blab);
               end
                ms = imbinarize(blab);
                B = labeloverlay((uint8(J1ma)),Imask,'Transparency',al,'Colormap',colmap);
                axes(app.Stru_Comp);
                imshow(((B)),[],'Parent',app.Stru_Comp)
                Rnp = imbinarize((double(Rnp)));
                jacc = num2str(jaccard(ms,Rnp));
                dce = num2str(dice(ms,Rnp));
                bf = num2str(bfscore(ms,Rnp));
                set(app.JaccardIndex,'Text',(jacc));
                set(app.DICEIndex,'Text',(dce));
                set(app.bfscoreindex,'Text',(bf));
            else 
                nulab = 3;
                Jnp = J1pe;
                Rnp = R1pe;
                B = labeloverlay((Jnp),Imask,'Transparency',al,'Colormap',colmap);
                axes(app.Stru_Comp);
                imshow(((B)),'Parent',app.Stru_Comp)
                ms = Lab_section(I,Imask,nulab);
                Rnp = imbinarize((double(Rnp)));
                jacc = num2str(jaccard(ms,Rnp));
                dce = num2str(dice(ms,Rnp));
                bf = num2str(bfscore(ms,Rnp));
                set(app.JaccardIndex,'Text',(jacc));
                set(app.DICEIndex,'Text',(dce));
                set(app.bfscoreindex,'Text',(bf));
            

            end
            set(app.JaccardIndex,'Text',(jacc));
            set(app.DICEIndex,'Text',(dce));
            set(app.bfscoreindex,'Text',(bf));
            set(app.SSIMIndex,'Text',(sstru));
        end

        % Button pushed function: BreastBW
        function BreastBWPushed(app, event)
        global R1ma R1pe Rnp L Lbw bin maROI;
        bin = 1;
        Rnp = R1ma;
        L = graythresh(Rnp);
        L = (imbinarize(Rnp,L));
        Lbw = bwareaopen(L,3500,26);
        maROI = imfuse(Lbw,R1pe,"diff");
        maROI = bwareaopen(maROI,1500,26);
        maROI = bwareaopen((imcomplement(maROI)),3500,26);
        maROI = imcomplement(maROI);
        maROI = bwareaopen((imcomplement(maROI)),3500,26);
        maROI = bwareaopen((imcomplement(maROI)),3500,26);
        axes(app.SegmentedROI);
        imshow(maROI,[],'Parent',app.SegmentedROI);
        end

        % Button pushed function: PectoralBW
        function PectoralBWPushed(app, event)
        global R1pe Rnp L bin Lbw;
        Rnp = R1pe;
        bin = 2;
        L = graythresh(Rnp);
        L = (imbinarize(Rnp,L));
        L = imcomplement(L);
        Lbw = bwareaopen(L,3500,26);
        Lbw = imcomplement(Lbw);
        Lbw = bwareaopen(Lbw,3500,26);
        Lbw = imcomplement(Lbw);
        Lbw = bwareaopen(Lbw,3500,26);
        Lbw = imcomplement(Lbw);  
        axes(app.SegmentedROI);
        imshow(Lbw,[],'Parent',app.SegmentedROI);
        end

        % Callback function
        function ROIOverlayedButtonGroupSelectionChanged(app, event)

        end

        % Selection changed function: ROIOverlaySelectorButtonGroup
        function ROIOverlaySelectorButtonGroupSelectionChanged2(app, event)
            selectedButton = app.ROIOverlaySelectorButtonGroup.SelectedObject;
            global over; %set global variable
            over = selectedButton.Text;
            if count(over,'Breast') == 1
                over = 1;
            elseif count(over,'Mama') == 1
                over = 2;
            end
        end

        % Selection changed function: ROIValidationSelectorButtonGroup
        function ROIValidationSelectorButtonGroupSelectionChanged(app, event)
            selectedButton = app.ROIValidationSelectorButtonGroup.SelectedObject;
            global refer; %set global variable
            refer = selectedButton.Text;
            if count(refer,'Breast') == 1
                refer = 1;
            elseif count(refer,'Mama') == 1
                refer = 2;
            end
        end

        % Button pushed function: LabelingButton
        function LabelingButtonPushed(app, event)
            global maROI J1ma J1pe Rnpbw2 Rnpbw3 gmag colmap L Lrgb B I al
            maROI = imsubtract((double(J1ma)),(double(J1pe)));
            maROI = imcomplement (maROI);
            maROI = im2gray(maROI);
            Rnpbw2 = imbinarize(maROI,'adaptive');
            Rnpbw3 = bwareaopen(Rnpbw2,3000,4);
            gmag = imgradient(Rnpbw3,'sobel');
            L = watershed(gmag);
            Lrgb = (label2rgb(L));
            B = labeloverlay((uint8(I)),L,"Colormap",colmap,'Transparency',al);
            axes(app.Stru_Comp);
            imshow(((B)),[],'Parent',app.Stru_Comp)
        end

        % Button pushed function: LabelsComparisonButton
        function LabelsComparisonButtonPushed(app, event)
            global Lrgb ifuse Imask L
            ifuse = imfuse(Imask,L,'diff');
            axes(app.Stru_Comp);
            imshow((uint8(ifuse)),[],'Parent',app.Stru_Comp)
            
        end

        % Button pushed function: SaveLabelsButton
        function SaveLabelsButtonPushed(app, event)
            global L labeled_folder filename...
                pathnameM filenameM;
           [filenameM,pathnameM] = uiputfile({'*.png'},...
           'Select a file',labeled_folder);
           if filename == 0
             % User clicked the Cancel button.
             return;
           end
           new_name = strcat(pathnameM,filename);
           imwrite(L,new_name);
        end

        % Callback function
        function UIFigureCloseRequest(app, event)
            
            
        end

        % Value changed function: xreg
        function xregValueChanged(app, event)

        end

        % Button pushed function: CLAHERefreshButton
        function CLAHERefreshButtonPushed(app, event)
            global clip xreg yreg I pathname filename
            I = imread(strcat(pathname,filename)); %read the file.
            clip = get(app.cliping);
                clip = clip.Value;
                clip = str2num(clip);
                xreg = get(app.xreg);
                xreg = xreg.Value;
                xreg = str2num(xreg);
                yreg = get(app.yreg);
                yreg = yreg.Value;
                yreg = str2num(yreg);
                if xreg == 0
                    xreg = 1;
                else
                end
                if yreg == 0
                    yreg = 2;
                else
                end
                if clip == 0
                    clip = 0.3;
                else
                end
                  
                I = clahe((double(I)),[xreg yreg],clip);          % 8 Imagen con CLAHE
                axes(app.OriginalImage);            %choose Iori as the display region.
                imshow((uint8(I)),[],'Parent',app.OriginalImage)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.LoadImageButton = uibutton(app.ControlsPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadImageButton.Icon = fullfile(pathToMLAPP, 'iconos', 'load.png');
            app.LoadImageButton.Text = 'Load Image';
            app.ImageenhancementLabel = uilabel(app.ControlsPanel);
            app.ImageenhancementLabel.Text = {'Image'; 'enhancement '};
            app.ImageenhancementDropDown = uidropdown(app.ControlsPanel);
            app.ImageenhancementDropDown.Items = {'None', 'Complement', 'Gauss HPF', 'Gauss LPF', 'CLAHE', 'Fuzzy', 'Gauss HPF Aperture'};
            app.ImageenhancementDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageenhancementDropDownValueChanged, true);
            app.ImageenhancementDropDown.Value = 'None';
            app.BreastSegment = uibutton(app.ControlsPanel, 'push');
            app.BreastSegment.ButtonPushedFcn = createCallbackFcn(app, @BreastSegmentPushed, true);
            app.BreastSegment.Text = 'Breast';
            app.PectoralSegment = uibutton(app.ControlsPanel, 'push');
            app.PectoralSegment.ButtonPushedFcn = createCallbackFcn(app, @PectoralSegmentPushed, true);
            app.PectoralSegment.Text = 'Pectoral';
            app.Label_3 = uilabel(app.ControlsPanel);
            app.Label_4 = uilabel(app.ControlsPanel);
            app.Label_5 = uilabel(app.ControlsPanel);
            app.ReferencecomparisonButton = uibutton(app.ControlsPanel, 'push');
            app.ReferencecomparisonButton.ButtonPushedFcn = createCallbackFcn(app, @ReferencecomparisonButtonPushed, true);
            app.ReferencecomparisonButton.Text = 'Reference comparison';
            app.ValidationLabel = uilabel(app.ControlsPanel);
            app.ValidationLabel.Text = 'Validation**';
            app.RegiontoSegmentLabel = uilabel(app.ControlsPanel);
            app.RegiontoSegmentLabel.Text = 'Region to Segment';
            app.BreastBW = uibutton(app.ControlsPanel, 'push');
            app.BreastBW.ButtonPushedFcn = createCallbackFcn(app, @BreastBWPushed, true);
            app.BreastBW.Text = 'Breast';
            app.PectoralBW = uibutton(app.ControlsPanel, 'push');
            app.PectoralBW.ButtonPushedFcn = createCallbackFcn(app, @PectoralBWPushed, true);
            app.PectoralBW.Text = 'Pectoral ';
            app.Label_6 = uilabel(app.ControlsPanel);
            app.BinarizedROILabel = uilabel(app.ControlsPanel);
            app.BinarizedROILabel.Text = 'Binarized ROI';
            app.SettingDistanceEditFieldLabel = uilabel(app.ControlsPanel);
            app.SettingDistanceEditFieldLabel.Text = 'Setting Distance';
            app.SettingDistanceEditField = uieditfield(app.ControlsPanel, 'text');
            app.SettingDistanceEditField.Value = '0';
            app.DefaultdistancePanel = uipanel(app.ControlsPanel);
            app.DefaultdistancePanel.Title = 'Default distance';
            app.DefaultdistanceforPM160DefaultdistanceforBM35Label = uilabel(app.DefaultdistancePanel);
            app.DefaultdistanceforPM160DefaultdistanceforBM35Label.Text = {' For Pectoral Muscle: '; ' For Breast Muscle: '};
            app.DefaultdistanceforPM160DefaultdistanceforBM35Label_2 = uilabel(app.DefaultdistancePanel);
            app.DefaultdistanceforPM160DefaultdistanceforBM35Label_2.Text = {'55'; '160'};
            app.ResetButton = uibutton(app.ControlsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed2, true);
            app.ResetButton.Text = 'Reset Button';
            app.ROIOverlayedButton = uibutton(app.ControlsPanel, 'push');
            app.ROIOverlayedButton.ButtonPushedFcn = createCallbackFcn(app, @ROIOverlayedButtonPushed, true);
            app.ROIOverlayedButton.Text = 'ROI Overlayed';
            app.ROIOverlaySelectorButtonGroup = uibuttongroup(app.ControlsPanel);
            app.ROIOverlaySelectorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ROIOverlaySelectorButtonGroupSelectionChanged2, true);
            app.ROIOverlaySelectorButtonGroup.Title = 'ROI Overlay Selector';
            app.PectoralButton_Laboevr = uitogglebutton(app.ROIOverlaySelectorButtonGroup);
            app.PectoralButton_Laboevr.Text = 'Pectoral ';
            app.PectoralButton_Laboevr.Value = true;
            app.MamaButton_Laboevr = uitogglebutton(app.ROIOverlaySelectorButtonGroup);
            app.MamaButton_Laboevr.Text = 'Mama';
            app.BreastButton_Laboevr = uitogglebutton(app.ROIOverlaySelectorButtonGroup);
            app.BreastButton_Laboevr.Text = 'Breast';
            app.ROIValidationSelectorButtonGroup = uibuttongroup(app.ControlsPanel);
            app.ROIValidationSelectorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ROIValidationSelectorButtonGroupSelectionChanged, true);
            app.ROIValidationSelectorButtonGroup.Title = 'ROI Validation Selector';
            app.PectoralButton_Validation = uitogglebutton(app.ROIValidationSelectorButtonGroup);
            app.PectoralButton_Validation.Text = 'Pectoral ';
            app.PectoralButton_Validation.Value = true;
            app.MamaButton_Validation = uitogglebutton(app.ROIValidationSelectorButtonGroup);
            app.MamaButton_Validation.Text = 'Mama';
            app.BreastButton_Validation = uitogglebutton(app.ROIValidationSelectorButtonGroup);
            app.BreastButton_Validation.Text = 'Breast';
            app.CLAHEControlsPanel = uipanel(app.ControlsPanel);
            app.CLAHEControlsPanel.Title = 'CLAHE Controls';
            app.xreg = uieditfield(app.CLAHEControlsPanel, 'text');
            app.xreg.ValueChangedFcn = createCallbackFcn(app, @xregValueChanged, true);
            app.xreg.Value = '0';
            app.cliping = uieditfield(app.CLAHEControlsPanel, 'text');
            app.cliping.Value = '0';
            app.yreg = uieditfield(app.CLAHEControlsPanel, 'text');
            app.yreg.Value = '0';
            app.reg_cont = uilabel(app.CLAHEControlsPanel);
            app.reg_cont.Text = 'Con. Regions [x y]';
            app.clipi_histo = uilabel(app.CLAHEControlsPanel);
            app.clipi_histo.Text = 'Histogram clipping';
            app.CLAHERefreshButton = uibutton(app.CLAHEControlsPanel, 'push');
            app.CLAHERefreshButton.ButtonPushedFcn = createCallbackFcn(app, @CLAHERefreshButtonPushed, true);
            app.CLAHERefreshButton.Text = 'CLAHE Refresh';
            app.ProcessingDisplayPanel = uipanel(app.UIFigure);
            app.ProcessingDisplayPanel.Title = 'Processing Display';
            app.OriginalImage = uiaxes(app.ProcessingDisplayPanel);
            app.OriginalImage.XTick = [];
            app.OriginalImage.XTickLabel = '';
            app.OriginalImage.YTick = [];
            app.OriginalImage.YTickLabel = '';
            app.OriginalImage.ZTick = [];
            app.OriginalImage.ZTickLabel = '';
            app.OriginalImage.ButtonDownFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.SegmentedROI = uiaxes(app.ProcessingDisplayPanel);
            app.SegmentedROI.XTick = [];
            app.SegmentedROI.YTick = [];
            app.Stru_Comp = uiaxes(app.ProcessingDisplayPanel);
            app.Stru_Comp.XTick = [];
            app.Stru_Comp.YTick = [];
            app.OriginalImageLabel = uilabel(app.ProcessingDisplayPanel);
            app.OriginalImageLabel.Text = 'Original Image';
            app.SegmentationAreaLabel = uilabel(app.ProcessingDisplayPanel);
            app.SegmentationAreaLabel.Text = 'Segmentation Area';
            app.StructuralComparisonLabel = uilabel(app.ProcessingDisplayPanel);
            app.StructuralComparisonLabel.Text = 'Structural Comparison';
            app.ROISemiAutomaticSegmentationandLabeling = uilabel(app.UIFigure);
            app.ROISemiAutomaticSegmentationandLabeling.Text = 'ROI Semi-Automatic Segmentation and Labeling';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'iconos', 'LogoCinvestavBlancosmall_logo.png');
            app.ByMCFaustoCortesLabel = uilabel(app.UIFigure);
            app.ByMCFaustoCortesLabel.Text = 'By M.C. Fausto Cortes';
            app.Panel = uipanel(app.UIFigure);
            app.JaccardIndex = uilabel(app.Panel);
            app.JaccardIndex.Text = '';
            app.bfscoreindex = uilabel(app.Panel);
            app.bfscoreindex.Text = {''; ''};
            app.JaccardLabel = uilabel(app.Panel);
            app.JaccardLabel.Text = 'Jaccard:';
            app.bfscoreLabel = uilabel(app.Panel);
            app.bfscoreLabel.Text = 'bf-score:';
            app.SSIMIndex = uilabel(app.Panel);
            app.SSIMIndex.Text = {''; ''};
            app.SSIMLabel = uilabel(app.Panel);
            app.SSIMLabel.Text = 'SSIM:';
            app.DICEIndex = uilabel(app.Panel);
            app.DICEIndex.Text = {''; ''};
            app.DICELabel = uilabel(app.Panel);
            app.DICELabel.Text = 'DICE:';
            app.Label_7 = uilabel(app.Panel);
            app.LabelingButton = uibutton(app.Panel, 'push');
            app.LabelingButton.ButtonPushedFcn = createCallbackFcn(app, @LabelingButtonPushed, true);
            app.LabelingButton.Text = 'Labeling';
            app.LabelsComparisonButton = uibutton(app.Panel, 'push');
            app.LabelsComparisonButton.ButtonPushedFcn = createCallbackFcn(app, @LabelsComparisonButtonPushed, true);
            app.LabelsComparisonButton.Text = 'Labels Comparison**';
            app.SaveLabelsButton = uibutton(app.Panel, 'push');
            app.SaveLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLabelsButtonPushed, true);
            app.SaveLabelsButton.Icon = fullfile(pathToMLAPP, 'iconos', 'load.png');
            app.SaveLabelsButton.Text = 'Save Labels';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'iconos', 'LogoCinvestavBlancosmall_letras.png');
            app.ComparedwithMIASdatasetLabel = uilabel(app.UIFigure);
            app.ComparedwithMIASdatasetLabel.Text = '**Compared against MIAS ground truth ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Segmenter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end